classdef Interf < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        BaseTextArea                    matlab.ui.control.TextArea
        BaseTextAreaLabel               matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        FonctionobjectiveTextArea       matlab.ui.control.TextArea
        FonctionobjectiveTextAreaLabel  matlab.ui.control.Label
        RsolutionButton                 matlab.ui.control.StateButton
        vecteurbTextArea                matlab.ui.control.TextArea
        vecteurbTextAreaLabel           matlab.ui.control.Label
        matricedescontriantesTextArea   matlab.ui.control.TextArea
        matricedescontriantesTextAreaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RsolutionButton
        function RsolutionButtonValueChanged(comp, event)
            value = comp.RsolutionButton.Value;
           CString = char(comp.FonctionobjectiveTextArea.Value);
AString = char(comp.matricedescontriantesTextArea.Value);
bString = char(comp.vecteurbTextArea.Value);
A = str2num(AString);
c = str2num(CString);
b = str2num(bString);
[B, Indice] = Base_Initiale(c, A, b);
BString = char(B.Value);
set(comp.BaseTextArea, 'Value', BString);
     
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 469 424];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.matricedescontriantesTextAreaLabel = uilabel(comp);
            comp.matricedescontriantesTextAreaLabel.Text = 'matrice des contriantes';
            comp.matricedescontriantesTextArea = uitextarea(comp);
            comp.vecteurbTextAreaLabel = uilabel(comp);
            comp.vecteurbTextAreaLabel.Text = 'vecteur b';
            comp.vecteurbTextArea = uitextarea(comp);
            comp.RsolutionButton = uibutton(comp, 'state');
            comp.RsolutionButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @RsolutionButtonValueChanged, true);
            comp.RsolutionButton.Text = 'Résolution';
            comp.FonctionobjectiveTextAreaLabel = uilabel(comp);
            comp.FonctionobjectiveTextAreaLabel.Text = 'Fonction objective';
            comp.FonctionobjectiveTextArea = uitextarea(comp);
            comp.FonctionobjectiveTextArea.Value = {'[1 ;2;3]'};
            comp.UITable = uitable(comp);
            comp.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            comp.UITable.RowName = {};
            comp.BaseTextAreaLabel = uilabel(comp);
            comp.BaseTextAreaLabel.Text = 'Base';
            comp.BaseTextArea = uitextarea(comp);
        end
    end
end